classdef segmentationOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SegmentationOptionsLabel        matlab.ui.control.Label
        PretrainedModelEditFieldLabel   matlab.ui.control.Label
        PretrainedModelEditField        matlab.ui.control.EditField
        SelectmodelButton               matlab.ui.control.Button
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.EditField
        LearnRateDropPeriodEditFieldLabel  matlab.ui.control.Label
        LearnRateDropPeriodEditField    matlab.ui.control.EditField
        LearnRateDropFactorEditFieldLabel  matlab.ui.control.Label
        LearnRateDropFactorEditField    matlab.ui.control.EditField
        MaxEpochEditFieldLabel          matlab.ui.control.Label
        MaxEpochEditField               matlab.ui.control.EditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.EditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.EditField
        PlotDropDownLabel               matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        ShuffleDataDropDownLabel        matlab.ui.control.Label
        ShuffleDataDropDown             matlab.ui.control.DropDown
        ExecutionEnvironmentDropDownLabel  matlab.ui.control.Label
        ExecutionEnvironmentDropDown    matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        mainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            if isempty(mainApp.comet_handles.segmentationOptions.modelPath) || strcmp(mainApp.comet_handles.segmentationOptions.modelPath,'Default')
                app.PretrainedModelEditField.Value = 'Default';
            else
                app.PretrainedModelEditField.Value = app.mainApp.comet_handles.segmentationOptions.modelPath;
            end
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            [fileName, path] = uigetfile('Select a pre-trained model!');
            if ischar(path)
                app.PretrainedModelEditField.Value = fullfile(path,fileName);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.comet_handles.segmentationOptions.modelPath = app.PretrainedModelEditField.Value;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SegmentationOptionsLabel = uilabel(app.UIFigure);
            app.SegmentationOptionsLabel.Text = 'Segmentation Options';
            app.PretrainedModelEditFieldLabel = uilabel(app.UIFigure);
            app.PretrainedModelEditFieldLabel.Text = 'Pre-trained Model';
            app.PretrainedModelEditField = uieditfield(app.UIFigure, 'text');
            app.PretrainedModelEditField.Value = 'Default';
            app.SelectmodelButton = uibutton(app.UIFigure, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            app.InitialLearnRateEditFieldLabel = uilabel(app.UIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'Initial Learn Rate';
            app.InitialLearnRateEditField = uieditfield(app.UIFigure, 'text');
            app.InitialLearnRateEditField.Value = '0.003';
            app.LearnRateDropPeriodEditFieldLabel = uilabel(app.UIFigure);
            app.LearnRateDropPeriodEditFieldLabel.Text = 'Learn Rate Drop Period';
            app.LearnRateDropPeriodEditField = uieditfield(app.UIFigure, 'text');
            app.LearnRateDropPeriodEditField.Value = '8';
            app.LearnRateDropFactorEditFieldLabel = uilabel(app.UIFigure);
            app.LearnRateDropFactorEditFieldLabel.Text = 'Learn Rate Drop Factor';
            app.LearnRateDropFactorEditField = uieditfield(app.UIFigure, 'text');
            app.LearnRateDropFactorEditField.Value = '0.3';
            app.MaxEpochEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEpochEditFieldLabel.Text = 'Max Epoch';
            app.MaxEpochEditField = uieditfield(app.UIFigure, 'text');
            app.MaxEpochEditField.Value = '50';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'Mini Batch Size';
            app.MiniBatchSizeEditField = uieditfield(app.UIFigure, 'text');
            app.MiniBatchSizeEditField.Value = '1';
            app.ValidationPatienceEditFieldLabel = uilabel(app.UIFigure);
            app.ValidationPatienceEditFieldLabel.Text = 'Validation Patience';
            app.ValidationPatienceEditField = uieditfield(app.UIFigure, 'text');
            app.ValidationPatienceEditField.Value = '10';
            app.PlotDropDownLabel = uilabel(app.UIFigure);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.UIFigure);
            app.PlotDropDown.Items = {'none', 'training-progress'};
            app.PlotDropDown.Value = 'none';
            app.ShuffleDataDropDownLabel = uilabel(app.UIFigure);
            app.ShuffleDataDropDownLabel.Text = 'Shuffle Data';
            app.ShuffleDataDropDown = uidropdown(app.UIFigure);
            app.ShuffleDataDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDataDropDown.Value = 'every-epoch';
            app.ExecutionEnvironmentDropDownLabel = uilabel(app.UIFigure);
            app.ExecutionEnvironmentDropDownLabel.Text = 'Execution Environment';
            app.ExecutionEnvironmentDropDown = uidropdown(app.UIFigure);
            app.ExecutionEnvironmentDropDown.Items = {'auto', 'cpu', 'gpu', 'multi-gpu', 'parallel', ''};
            app.ExecutionEnvironmentDropDown.Value = 'auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end